classdef PRoyectofinal_OA_LJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConectarButton                 matlab.ui.control.Button
        GuardarButton                  matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        FiltrosButtonGroup             matlab.ui.container.ButtonGroup
        IIRButton                      matlab.ui.control.RadioButton
        FIRButton                      matlab.ui.control.RadioButton
        OriginalButton                 matlab.ui.control.RadioButton
        Propiedadesparaguardar___________Label  matlab.ui.control.Label
        TiempodeGrabacionLabel         matlab.ui.control.Label
        UnidadesdeTiempoDropDownLabel  matlab.ui.control.Label
        UnidadesdeTiempoDropDown       matlab.ui.control.DropDown
        AplicarFiltroButton            matlab.ui.control.Button
        PuertoComEditFieldLabel        matlab.ui.control.Label
        PuertoComEditField             matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Puerto_serial
        senial
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
         global tiva, 
            tiva = serialport(app.Puerto_serial, 1000000); %el puerto puede variar entre computadores
          %frecuecia de muestreo en Hz.
          fs = 100;
          % vectores de datos 
          N = 5*fs;
          app.senial = zeros(1,N);
          n = 1:N;
          flush(tiva);
          for i=n
          app.senial(i) = readline(tiva);
          end  
        end

        % Value changed function: PuertoComEditField
        function PuertoComEditFieldValueChanged(app, event)
            app.Puerto_serial = app.PuertoComEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.FiltrosButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltrosButtonGroup.Title = 'Filtros ';
            app.IIRButton = uiradiobutton(app.FiltrosButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.FiltrosButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.OriginalButton = uiradiobutton(app.FiltrosButtonGroup);
            app.OriginalButton.Text = 'Original ';
            app.Propiedadesparaguardar___________Label = uilabel(app.UIFigure);
            app.Propiedadesparaguardar___________Label.Text = '     __________ Propiedades para guardar  ___________';
            app.TiempodeGrabacionLabel = uilabel(app.UIFigure);
            app.TiempodeGrabacionLabel.Text = 'Tiempo de Grabacion ';
            app.UnidadesdeTiempoDropDownLabel = uilabel(app.UIFigure);
            app.UnidadesdeTiempoDropDownLabel.Text = 'Unidades de Tiempo ';
            app.UnidadesdeTiempoDropDown = uidropdown(app.UIFigure);
            app.UnidadesdeTiempoDropDown.Items = {'segundos ', 'Milisegundos', 'Microsegundos'};
            app.UnidadesdeTiempoDropDown.Value = 'Milisegundos';
            app.AplicarFiltroButton = uibutton(app.UIFigure, 'push');
            app.AplicarFiltroButton.Text = 'Aplicar Filtro ';
            app.PuertoComEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoComEditFieldLabel.Text = 'Puerto Com';
            app.PuertoComEditField = uieditfield(app.UIFigure, 'text');
            app.PuertoComEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoComEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRoyectofinal_OA_LJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end